classdef TheApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        HomeTab                         matlab.ui.container.Tab
        Image5                          matlab.ui.control.Image
        Image4                          matlab.ui.control.Image
        Image3                          matlab.ui.control.Image
        Image2                          matlab.ui.control.Image
        Image                           matlab.ui.control.Image
        YourveryownpersonalstocktrackingappLabel  matlab.ui.control.Label
        WelcometoStockTraderProLabel    matlab.ui.control.Label
        PercentGainTab                  matlab.ui.container.Tab
        GridCheckBox_4                  matlab.ui.control.CheckBox
        TICKER                          matlab.ui.control.DropDown
        EnterthetickerofyourdesiredindexDropDownLabel  matlab.ui.control.Label
        TotalProfitEditField            matlab.ui.control.EditField
        TotalProfitEditFieldLabel       matlab.ui.control.Label
        PercentIncreaseEditField        matlab.ui.control.EditField
        PercentIncreaseEditFieldLabel   matlab.ui.control.Label
        lllllllllllllllllllllllllllLabel  matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        EnterprincipalinvestmentEditField  matlab.ui.control.NumericEditField
        EnterprincipalinvestmentLabel   matlab.ui.control.Label
        DateOfSaleLabel                 matlab.ui.control.Label
        Percent_Gain_Calculator_DOS     matlab.ui.control.DatePicker
        Label                           matlab.ui.control.Label
        Percent_Gain_Calculator_DOP     matlab.ui.control.DatePicker
        DateofPurchaseLabel             matlab.ui.control.Label
        PercentGainCalculatorLabel      matlab.ui.control.Label
        UIAxes2                         matlab.ui.control.UIAxes
        EconomicOverviewTab             matlab.ui.container.Tab
        GridCheckBox_3                  matlab.ui.control.CheckBox
        MediumCompanyPercentIncreaseEditField  matlab.ui.control.EditField
        MediumCompanyPercentIncreaseEditFieldLabel  matlab.ui.control.Label
        lllllllllllllllLabel            matlab.ui.control.Label
        Theanswertocompanysizewellquestion  matlab.ui.control.EditField
        ConclusionLabel                 matlab.ui.control.Label
        EconomicOverviewofLargevsSmallCompaniesLabel  matlab.ui.control.Label
        LargeCompanyPercentIncreaseEditField  matlab.ui.control.EditField
        LargeCompanyPercentIncreaseEditFieldLabel  matlab.ui.control.Label
        SmallCompanyPercentIncreaseEditField  matlab.ui.control.EditField
        SmallCompanyPercentIncreaseEditFieldLabel  matlab.ui.control.Label
        TimePeriodStartLabel            matlab.ui.control.Label
        DOPLARGE                        matlab.ui.control.DatePicker
        Label_5                         matlab.ui.control.Label
        DOSLARGE                        matlab.ui.control.DatePicker
        TimePeriodEndLabel              matlab.ui.control.Label
        Label_6                         matlab.ui.control.Label
        Label_7                         matlab.ui.control.Label
        UIAxes2_2                       matlab.ui.control.UIAxes
        IndexPriceTab                   matlab.ui.container.Tab
        GridCheckBox                    matlab.ui.control.CheckBox
        HistoryPriceIndexDropDown       matlab.ui.control.DropDown
        HistoryPriceIndexDropDownLabel  matlab.ui.control.Label
        PriceEditField                  matlab.ui.control.NumericEditField
        PriceLabel                      matlab.ui.control.Label
        TickerDropDown                  matlab.ui.control.DropDown
        TickerDropDownLabel             matlab.ui.control.Label
        PriceofanIndexOnAParticularDateLabel  matlab.ui.control.Label
        lllllllllllllllLabel_3          matlab.ui.control.Label
        DateLabel                       matlab.ui.control.Label
        DatePicker_4                    matlab.ui.control.DatePicker
        Label_14                        matlab.ui.control.Label
        Label_13                        matlab.ui.control.Label
        Label_12                        matlab.ui.control.Label
        plotAxes                        matlab.ui.control.UIAxes
        InvestmentOptionsTab            matlab.ui.container.Tab
        GridCheckBox_2                  matlab.ui.control.CheckBox
        GoldPercentIncreaseEditField    matlab.ui.control.NumericEditField
        GoldPercentIncreaseEditFieldLabel  matlab.ui.control.Label
        Russell2000PercentIncreaseEditField  matlab.ui.control.NumericEditField
        Russell2000PercentIncreaseEditFieldLabel  matlab.ui.control.Label
        Russell3000PercentIncreaseEditField  matlab.ui.control.NumericEditField
        Russell3000PercentIncreaseEditFieldLabel  matlab.ui.control.Label
        SP500PercentIncreaseEditField   matlab.ui.control.NumericEditField
        SP500PercentIncreaseEditFieldLabel  matlab.ui.control.Label
        NASDAQPercentIncreaseEditField  matlab.ui.control.NumericEditField
        NASDAQPercentIncreaseEditFieldLabel  matlab.ui.control.Label
        DOWPercentIncreaseEditField     matlab.ui.control.NumericEditField
        DOWPercentIncreaseEditFieldLabel  matlab.ui.control.Label
        Label_11                        matlab.ui.control.Label
        WhatYouShouldHaveInvestedInLabel  matlab.ui.control.Label
        ConclusionEditField_2           matlab.ui.control.EditField
        ConclusionEditField_2Label      matlab.ui.control.Label
        lllllllllllllllLabel_2          matlab.ui.control.Label
        TimePeriodStartLabel_2          matlab.ui.control.Label
        DatePicker_3                    matlab.ui.control.DatePicker
        Label_10                        matlab.ui.control.Label
        rDatePicker_3                   matlab.ui.control.DatePicker
        TimePeriodEndLabel_2            matlab.ui.control.Label
        Label_9                         matlab.ui.control.Label
        Label_8                         matlab.ui.control.Label
        UIAxes3                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
    
        end

        % Value changed function: DatePicker_4
        function DatePicker_4ValueChanged(app, event)
            value = app.DatePicker_4.Value;
            Tab3F(app)
        end

        % Value changed function: TickerDropDown
        function TickerDropDownValueChanged(app, event)
            value = app.TickerDropDown.Value;
            Tab3F(app)
        end

        % Value changed function: PriceEditField
        function PriceEditFieldValueChanged(app, event)
            value = app.PriceEditField.Value;
            Tab3F(app)
        end

        % Value changed function: HistoryPriceIndexDropDown
        function HistoryPriceIndexDropDownValueChanged(app, event)
            value = app.HistoryPriceIndexDropDown.Value;
            Tab32F(app)
        end

        % Button down function: plotAxes
        function plotAxesButtonDown(app, event)
            Tab32F(app)
        end

        % Value changed function: GridCheckBox
        function GridCheckBoxValueChanged(app, event)
            value = app.GridCheckBox.Value;
            Tab32F(app)
        end

        % Value changed function: DatePicker_3
        function DatePicker_3ValueChanged(app, event)
            value = app.DatePicker_3.Value;
            Tab4F(app)
        end

        % Value changed function: rDatePicker_3
        function rDatePicker_3ValueChanged(app, event)
            value = app.rDatePicker_3.Value;
            Tab4F(app)
        end

        % Value changed function: ConclusionEditField_2
        function ConclusionEditField_2ValueChanged(app, event)
            value = app.ConclusionEditField_2.Value;
            Tab4F(app)
        end

        % Button down function: UIAxes3
        function UIAxes3ButtonDown(app, event)
            Tab4F(app)
        end

        % Value changed function: DOWPercentIncreaseEditField
        function DOWPercentIncreaseEditFieldValueChanged2(app, event)
            value = app.DOWPercentIncreaseEditField.Value;
            Tab4F(app)
        end

        % Value changed function: NASDAQPercentIncreaseEditField
        function NASDAQPercentIncreaseEditFieldValueChanged2(app, event)
            value = app.NASDAQPercentIncreaseEditField.Value;
            Tab4F(app)
        end

        % Value changed function: SP500PercentIncreaseEditField
        function SP500PercentIncreaseEditFieldValueChanged2(app, event)
            value = app.SP500PercentIncreaseEditField.Value;
            Tab4F(app)
        end

        % Value changed function: Russell3000PercentIncreaseEditField
        function Russell3000PercentIncreaseEditFieldValueChanged2(app, event)
            value = app.Russell3000PercentIncreaseEditField.Value;
            Tab4F(app)
        end

        % Value changed function: Russell2000PercentIncreaseEditField
        function Russell2000PercentIncreaseEditFieldValueChanged2(app, event)
            value = app.Russell2000PercentIncreaseEditField.Value;
            Tab4F(app)
        end

        % Value changed function: GoldPercentIncreaseEditField
        function GoldPercentIncreaseEditFieldValueChanged2(app, event)
            value = app.GoldPercentIncreaseEditField.Value;
            Tab4F(app)
        end

        % Value changed function: GridCheckBox_2
        function GridCheckBox_2ValueChanged(app, event)
            value = app.GridCheckBox_2.Value;
            Tab4F(app)
        end

        % Value changed function: Percent_Gain_Calculator_DOP
        function Percent_Gain_Calculator_DOPValueChanged(app, event)
            DOP = app.Percent_Gain_Calculator_DOP.Value;
            Percent_Gain_Calculator(app)
        end

        % Value changed function: Percent_Gain_Calculator_DOS
        function Percent_Gain_Calculator_DOSValueChanged(app, event)
            value = app.Percent_Gain_Calculator_DOS.Value;
            Percent_Gain_Calculator(app)
        end

        % Value changed function: TICKER
        function TICKERValueChanged(app, event)
            tjwejfpw = app.TICKER.Value;
            Percent_Gain_Calculator(app)
        end

        % Value changed function: EnterprincipalinvestmentEditField
        function EnterprincipalinvestmentEditFieldValueChanged(app, event)
            Principle = app.EnterprincipalinvestmentEditField.Value;
            Percent_Gain_Calculator(app)
        end

        % Value changed function: PercentIncreaseEditField
        function PercentIncreaseEditFieldValueChanged(app, event)
            PercentIncrease = app.PercentIncreaseEditField.Value;
            Percent_Gain_Calculator(app)
        end

        % Value changed function: TotalProfitEditField
        function TotalProfitEditFieldValueChanged(app, event)
            TotalProfit = app.TotalProfitEditField.Value;
            Percent_Gain_Calculator(app)
        end

        % Button down function: UIAxes2
        function UIAxes2ButtonDown(app, event)
            Percent_Gain_Calculator(app)
        end

        % Value changed function: DOPLARGE
        function DOPLARGEValueChanged(app, event)
            DOPLARGEE = app.DOPLARGE.Value;
            How_Well_Did_Economy_Do(app)
        end

        % Value changed function: DOSLARGE
        function DOSLARGEValueChanged(app, event)
            DOSLARGEE = app.DOSLARGE.Value;
            How_Well_Did_Economy_Do(app)
        end

        % Value changed function: Theanswertocompanysizewellquestion
        function TheanswertocompanysizewellquestionValueChanged(app, event)
            ckdfjodjkpk = app.Theanswertocompanysizewellquestion.Value;
            How_Well_Did_Economy_Do(app)
        end

        % Value changed function: SmallCompanyPercentIncreaseEditField
        function SmallCompanyPercentIncreaseEditFieldValueChanged(app, event)
            value = app.SmallCompanyPercentIncreaseEditField.Value;
            
        end

        % Button down function: UIAxes2_2
        function UIAxes2_2ButtonDown(app, event)
            How_Well_Did_Economy_Do(app)
        end

        % Value changed function: GridCheckBox_4
        function GridCheckBox_4ValueChanged(app, event)
            value = app.GridCheckBox_4.Value;
            Percent_Gain_Calculator(app)
        end

        % Value changed function: GridCheckBox_3
        function GridCheckBox_3ValueChanged(app, event)
            value = app.GridCheckBox_3.Value;
            How_Well_Did_Economy_Do(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.TabGroup = uitabgroup(app.UIFigure);
            app.HomeTab = uitab(app.TabGroup);
            app.HomeTab.Title = 'Home';
            app.WelcometoStockTraderProLabel = uilabel(app.HomeTab);
            app.WelcometoStockTraderProLabel.Text = {'Welcome to Stock Trader Pro!'; ''};
            app.YourveryownpersonalstocktrackingappLabel = uilabel(app.HomeTab);
            app.YourveryownpersonalstocktrackingappLabel.Text = 'Your very own personal stock tracking app!';
            app.Image = uiimage(app.HomeTab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Stonks guy.png');
            app.Image2 = uiimage(app.HomeTab);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'wall street bets guy.jpg');
            app.Image3 = uiimage(app.HomeTab);
            app.Image3.ImageSource = fullfile(pathToMLAPP, '19299.jpg');
            app.Image4 = uiimage(app.HomeTab);
            app.Image4.ImageSource = fullfile(pathToMLAPP, 'Masterpiece.jpg');
            app.Image5 = uiimage(app.HomeTab);
            app.Image5.ImageSource = fullfile(pathToMLAPP, 'tenor.gif');
            app.PercentGainTab = uitab(app.TabGroup);
            app.PercentGainTab.Title = 'Percent Gain';
            app.UIAxes2 = uiaxes(app.PercentGainTab);
            title(app.UIAxes2, 'Percent Change vs. Stock Trading Days')
            xlabel(app.UIAxes2, {'Stock Trading Days'; ''; 'Day 0 = September 10, 1987'; 'Day 8932 = February 17, 2023'})
            ylabel(app.UIAxes2, 'Percentage Change')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.ButtonDownFcn = createCallbackFcn(app, @UIAxes2ButtonDown, true);
            app.PercentGainCalculatorLabel = uilabel(app.PercentGainTab);
            app.PercentGainCalculatorLabel.Text = 'Percent Gain Calculator';
            app.DateofPurchaseLabel = uilabel(app.PercentGainTab);
            app.DateofPurchaseLabel.Text = 'Date of Purchase';
            app.Percent_Gain_Calculator_DOP = uidatepicker(app.PercentGainTab);
            app.Percent_Gain_Calculator_DOP.Limits = [datetime([1987 9 10]) datetime([2023 2 17])];
            app.Percent_Gain_Calculator_DOP.DisabledDaysOfWeek = [1 7];
            app.Percent_Gain_Calculator_DOP.ValueChangedFcn = createCallbackFcn(app, @Percent_Gain_Calculator_DOPValueChanged, true);
            app.Label = uilabel(app.PercentGainTab);
            app.Label.Text = '________________________________________________________________________________________________';
            app.Percent_Gain_Calculator_DOS = uidatepicker(app.PercentGainTab);
            app.Percent_Gain_Calculator_DOS.Limits = [datetime([1987 9 10]) datetime([2023 2 17])];
            app.Percent_Gain_Calculator_DOS.DisabledDaysOfWeek = [1 7];
            app.Percent_Gain_Calculator_DOS.ValueChangedFcn = createCallbackFcn(app, @Percent_Gain_Calculator_DOSValueChanged, true);
            app.DateOfSaleLabel = uilabel(app.PercentGainTab);
            app.DateOfSaleLabel.Text = 'Date Of Sale';
            app.EnterprincipalinvestmentLabel = uilabel(app.PercentGainTab);
            app.EnterprincipalinvestmentLabel.Text = 'Enter principal investment ($):';
            app.EnterprincipalinvestmentEditField = uieditfield(app.PercentGainTab, 'numeric');
            app.EnterprincipalinvestmentEditField.Limits = [1 Inf];
            app.EnterprincipalinvestmentEditField.ValueChangedFcn = createCallbackFcn(app, @EnterprincipalinvestmentEditFieldValueChanged, true);
            app.EnterprincipalinvestmentEditField.Value = 1;
            app.Label_2 = uilabel(app.PercentGainTab);
            app.Label_2.Text = '________________________________';
            app.Label_3 = uilabel(app.PercentGainTab);
            app.Label_3.Text = '________________________________';
            app.Label_4 = uilabel(app.PercentGainTab);
            app.Label_4.Text = '________________________________';
            app.lllllllllllllllllllllllllllLabel = uilabel(app.PercentGainTab);
            app.lllllllllllllllllllllllllllLabel.Text = {'l'; 'l'; 'l'; 'l'; 'l'; 'l'; 'l'; 'l'; 'l'; 'l'; 'l'; 'l'; 'l'; 'l'; 'l'; 'l'; 'l'; 'l'; 'l'; 'l'; 'l'; 'l'; 'l'; 'l'; 'l'; 'l'; 'l'; ''};
            app.PercentIncreaseEditFieldLabel = uilabel(app.PercentGainTab);
            app.PercentIncreaseEditFieldLabel.Text = 'Percent Increase:';
            app.PercentIncreaseEditField = uieditfield(app.PercentGainTab, 'text');
            app.PercentIncreaseEditField.ValueChangedFcn = createCallbackFcn(app, @PercentIncreaseEditFieldValueChanged, true);
            app.PercentIncreaseEditField.Editable = 'off';
            app.TotalProfitEditFieldLabel = uilabel(app.PercentGainTab);
            app.TotalProfitEditFieldLabel.Text = 'Total Profit';
            app.TotalProfitEditField = uieditfield(app.PercentGainTab, 'text');
            app.TotalProfitEditField.ValueChangedFcn = createCallbackFcn(app, @TotalProfitEditFieldValueChanged, true);
            app.TotalProfitEditField.Editable = 'off';
            app.EnterthetickerofyourdesiredindexDropDownLabel = uilabel(app.PercentGainTab);
            app.EnterthetickerofyourdesiredindexDropDownLabel.Text = 'Enter the ticker of your desired index';
            app.TICKER = uidropdown(app.PercentGainTab);
            app.TICKER.Items = {'Select a ticker', 'DOW', 'NASDAQ', 'S&P 500', 'Russell 3000', 'Russell 2000', 'GOLD'};
            app.TICKER.ValueChangedFcn = createCallbackFcn(app, @TICKERValueChanged, true);
            app.TICKER.Value = 'Select a ticker';
            app.GridCheckBox_4 = uicheckbox(app.PercentGainTab);
            app.GridCheckBox_4.ValueChangedFcn = createCallbackFcn(app, @GridCheckBox_4ValueChanged, true);
            app.GridCheckBox_4.Text = 'Grid';
            app.EconomicOverviewTab = uitab(app.TabGroup);
            app.EconomicOverviewTab.Title = 'Economic Overview';
            app.UIAxes2_2 = uiaxes(app.EconomicOverviewTab);
            title(app.UIAxes2_2, 'Percentage Change vs. Stock Trading Days')
            xlabel(app.UIAxes2_2, {'Stock Trading Days'; ''; 'Day 0 = September 10, 1987'; 'Day 8932 = February 17, 2023'})
            ylabel(app.UIAxes2_2, 'Percentage Change ')
            zlabel(app.UIAxes2_2, 'Z')
            app.UIAxes2_2.ButtonDownFcn = createCallbackFcn(app, @UIAxes2_2ButtonDown, true);
            app.Label_7 = uilabel(app.EconomicOverviewTab);
            app.Label_7.Text = '________________________________';
            app.Label_6 = uilabel(app.EconomicOverviewTab);
            app.Label_6.Text = '________________________________';
            app.TimePeriodEndLabel = uilabel(app.EconomicOverviewTab);
            app.TimePeriodEndLabel.Text = 'Time Period End';
            app.DOSLARGE = uidatepicker(app.EconomicOverviewTab);
            app.DOSLARGE.Limits = [datetime([1987 9 10]) datetime([2023 2 17])];
            app.DOSLARGE.DisabledDaysOfWeek = [1 7];
            app.DOSLARGE.ValueChangedFcn = createCallbackFcn(app, @DOSLARGEValueChanged, true);
            app.Label_5 = uilabel(app.EconomicOverviewTab);
            app.Label_5.Text = '________________________________________________________________________________________________';
            app.DOPLARGE = uidatepicker(app.EconomicOverviewTab);
            app.DOPLARGE.Limits = [datetime([1987 9 10]) datetime([2023 2 17])];
            app.DOPLARGE.DisabledDates = datetime([2023 4 24]);
            app.DOPLARGE.DisabledDaysOfWeek = [1 7];
            app.DOPLARGE.ValueChangedFcn = createCallbackFcn(app, @DOPLARGEValueChanged, true);
            app.TimePeriodStartLabel = uilabel(app.EconomicOverviewTab);
            app.TimePeriodStartLabel.Text = 'Time Period Start';
            app.SmallCompanyPercentIncreaseEditFieldLabel = uilabel(app.EconomicOverviewTab);
            app.SmallCompanyPercentIncreaseEditFieldLabel.Text = 'Small Company Percent Increase:';
            app.SmallCompanyPercentIncreaseEditField = uieditfield(app.EconomicOverviewTab, 'text');
            app.SmallCompanyPercentIncreaseEditField.ValueChangedFcn = createCallbackFcn(app, @SmallCompanyPercentIncreaseEditFieldValueChanged, true);
            app.SmallCompanyPercentIncreaseEditField.Editable = 'off';
            app.LargeCompanyPercentIncreaseEditFieldLabel = uilabel(app.EconomicOverviewTab);
            app.LargeCompanyPercentIncreaseEditFieldLabel.Text = 'Large Company Percent Increase';
            app.LargeCompanyPercentIncreaseEditField = uieditfield(app.EconomicOverviewTab, 'text');
            app.LargeCompanyPercentIncreaseEditField.Editable = 'off';
            app.EconomicOverviewofLargevsSmallCompaniesLabel = uilabel(app.EconomicOverviewTab);
            app.EconomicOverviewofLargevsSmallCompaniesLabel.Text = 'Economic Overview of Large vs. Small Companies';
            app.ConclusionLabel = uilabel(app.EconomicOverviewTab);
            app.ConclusionLabel.Text = 'Conclusion:';
            app.Theanswertocompanysizewellquestion = uieditfield(app.EconomicOverviewTab, 'text');
            app.Theanswertocompanysizewellquestion.ValueChangedFcn = createCallbackFcn(app, @TheanswertocompanysizewellquestionValueChanged, true);
            app.Theanswertocompanysizewellquestion.Editable = 'off';
            app.lllllllllllllllLabel = uilabel(app.EconomicOverviewTab);
            app.lllllllllllllllLabel.Text = {'l'; 'l'; 'l'; 'l'; 'l'; 'l'; 'l'; 'l'; 'l'; 'l'; 'l'; 'l'; 'l'; 'l'; 'l'; ''};
            app.MediumCompanyPercentIncreaseEditFieldLabel = uilabel(app.EconomicOverviewTab);
            app.MediumCompanyPercentIncreaseEditFieldLabel.Text = 'Medium Company Percent Increase:';
            app.MediumCompanyPercentIncreaseEditField = uieditfield(app.EconomicOverviewTab, 'text');
            app.MediumCompanyPercentIncreaseEditField.Editable = 'off';
            app.GridCheckBox_3 = uicheckbox(app.EconomicOverviewTab);
            app.GridCheckBox_3.ValueChangedFcn = createCallbackFcn(app, @GridCheckBox_3ValueChanged, true);
            app.GridCheckBox_3.Text = 'Grid';
            app.IndexPriceTab = uitab(app.TabGroup);
            app.IndexPriceTab.Title = 'Index Price';
            app.plotAxes = uiaxes(app.IndexPriceTab);
            title(app.plotAxes, 'Stock Price vs. Stock Trading Days')
            xlabel(app.plotAxes, {'Stock Trading Days'; ''; 'Day 0 = September 10, 1987'; 'Day 8932 = February 17, 2023'})
            ylabel(app.plotAxes, 'Price ($)')
            zlabel(app.plotAxes, 'Z')
            app.plotAxes.ButtonDownFcn = createCallbackFcn(app, @plotAxesButtonDown, true);
            app.Label_12 = uilabel(app.IndexPriceTab);
            app.Label_12.Text = '________________________________';
            app.Label_13 = uilabel(app.IndexPriceTab);
            app.Label_13.Text = '________________________________';
            app.Label_14 = uilabel(app.IndexPriceTab);
            app.Label_14.Text = '________________________________________________________________________________________________';
            app.DatePicker_4 = uidatepicker(app.IndexPriceTab);
            app.DatePicker_4.Limits = [datetime([1987 9 10]) datetime([2023 2 17])];
            app.DatePicker_4.DisabledDaysOfWeek = [1 7];
            app.DatePicker_4.ValueChangedFcn = createCallbackFcn(app, @DatePicker_4ValueChanged, true);
            app.DateLabel = uilabel(app.IndexPriceTab);
            app.DateLabel.Text = 'Date';
            app.lllllllllllllllLabel_3 = uilabel(app.IndexPriceTab);
            app.lllllllllllllllLabel_3.Text = {'l'; 'l'; 'l'; 'l'; 'l'; 'l'; 'l'; 'l'; 'l'; 'l'; 'l'; 'l'; 'l'; 'l'; 'l'; ''};
            app.PriceofanIndexOnAParticularDateLabel = uilabel(app.IndexPriceTab);
            app.PriceofanIndexOnAParticularDateLabel.Text = 'Price of an Index On A Particular Date';
            app.TickerDropDownLabel = uilabel(app.IndexPriceTab);
            app.TickerDropDownLabel.Text = 'Ticker';
            app.TickerDropDown = uidropdown(app.IndexPriceTab);
            app.TickerDropDown.Items = {'DOW', 'NASDAQ', 'S&P 500', 'Russell 3000', 'Russell 2000', 'Gold'};
            app.TickerDropDown.ValueChangedFcn = createCallbackFcn(app, @TickerDropDownValueChanged, true);
            app.TickerDropDown.Value = 'DOW';
            app.PriceLabel = uilabel(app.IndexPriceTab);
            app.PriceLabel.Text = 'Price ($)';
            app.PriceEditField = uieditfield(app.IndexPriceTab, 'numeric');
            app.PriceEditField.ValueChangedFcn = createCallbackFcn(app, @PriceEditFieldValueChanged, true);
            app.PriceEditField.Editable = 'off';
            app.HistoryPriceIndexDropDownLabel = uilabel(app.IndexPriceTab);
            app.HistoryPriceIndexDropDownLabel.Text = 'History Price Index';
            app.HistoryPriceIndexDropDown = uidropdown(app.IndexPriceTab);
            app.HistoryPriceIndexDropDown.Items = {'Select a ticker', 'DOW', 'NASDAQ', 'S&P 500', 'Russell 3000', 'Russell 2000', 'Gold', 'All'};
            app.HistoryPriceIndexDropDown.ValueChangedFcn = createCallbackFcn(app, @HistoryPriceIndexDropDownValueChanged, true);
            app.HistoryPriceIndexDropDown.Value = 'Select a ticker';
            app.GridCheckBox = uicheckbox(app.IndexPriceTab);
            app.GridCheckBox.ValueChangedFcn = createCallbackFcn(app, @GridCheckBoxValueChanged, true);
            app.GridCheckBox.Text = 'Grid';
            app.InvestmentOptionsTab = uitab(app.TabGroup);
            app.InvestmentOptionsTab.Title = 'Investment Options';
            app.UIAxes3 = uiaxes(app.InvestmentOptionsTab);
            title(app.UIAxes3, 'Price Relative to Origin vs. Stock Trading Days')
            xlabel(app.UIAxes3, {'Stock Trading Days'; ''; 'Day 0 = September 10, 1987'; 'Day 8932 = February 17, 2023'})
            ylabel(app.UIAxes3, 'Price Relative to Origin')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes3.ButtonDownFcn = createCallbackFcn(app, @UIAxes3ButtonDown, true);
            app.Label_8 = uilabel(app.InvestmentOptionsTab);
            app.Label_8.Text = '________________________________';
            app.Label_9 = uilabel(app.InvestmentOptionsTab);
            app.Label_9.Text = '________________________________';
            app.TimePeriodEndLabel_2 = uilabel(app.InvestmentOptionsTab);
            app.TimePeriodEndLabel_2.Text = 'Time Period End';
            app.rDatePicker_3 = uidatepicker(app.InvestmentOptionsTab);
            app.rDatePicker_3.Limits = [datetime([1987 9 10]) datetime([2023 2 17])];
            app.rDatePicker_3.DisabledDaysOfWeek = [1 7];
            app.rDatePicker_3.ValueChangedFcn = createCallbackFcn(app, @rDatePicker_3ValueChanged, true);
            app.Label_10 = uilabel(app.InvestmentOptionsTab);
            app.Label_10.Text = '________________________________________________________________________________________________';
            app.DatePicker_3 = uidatepicker(app.InvestmentOptionsTab);
            app.DatePicker_3.Limits = [datetime([1987 9 10]) datetime([2023 2 17])];
            app.DatePicker_3.DisabledDaysOfWeek = [1 7];
            app.DatePicker_3.ValueChangedFcn = createCallbackFcn(app, @DatePicker_3ValueChanged, true);
            app.TimePeriodStartLabel_2 = uilabel(app.InvestmentOptionsTab);
            app.TimePeriodStartLabel_2.Text = 'Time Period Start';
            app.lllllllllllllllLabel_2 = uilabel(app.InvestmentOptionsTab);
            app.lllllllllllllllLabel_2.Text = {'l'; 'l'; 'l'; 'l'; 'l'; 'l'; 'l'; 'l'; 'l'; 'l'; 'l'; 'l'; 'l'; 'l'; 'l'; ''};
            app.ConclusionEditField_2Label = uilabel(app.InvestmentOptionsTab);
            app.ConclusionEditField_2Label.Text = 'Conclusion:';
            app.ConclusionEditField_2 = uieditfield(app.InvestmentOptionsTab, 'text');
            app.ConclusionEditField_2.ValueChangedFcn = createCallbackFcn(app, @ConclusionEditField_2ValueChanged, true);
            app.ConclusionEditField_2.Editable = 'off';
            app.WhatYouShouldHaveInvestedInLabel = uilabel(app.InvestmentOptionsTab);
            app.WhatYouShouldHaveInvestedInLabel.Text = 'What You Should Have Invested In';
            app.Label_11 = uilabel(app.InvestmentOptionsTab);
            app.Label_11.Text = '_____________________________________________________________________________________________________________';
            app.DOWPercentIncreaseEditFieldLabel = uilabel(app.InvestmentOptionsTab);
            app.DOWPercentIncreaseEditFieldLabel.Text = 'DOW Percent Increase';
            app.DOWPercentIncreaseEditField = uieditfield(app.InvestmentOptionsTab, 'numeric');
            app.DOWPercentIncreaseEditField.ValueChangedFcn = createCallbackFcn(app, @DOWPercentIncreaseEditFieldValueChanged2, true);
            app.DOWPercentIncreaseEditField.Editable = 'off';
            app.NASDAQPercentIncreaseEditFieldLabel = uilabel(app.InvestmentOptionsTab);
            app.NASDAQPercentIncreaseEditFieldLabel.Text = 'NASDAQ Percent Increase';
            app.NASDAQPercentIncreaseEditField = uieditfield(app.InvestmentOptionsTab, 'numeric');
            app.NASDAQPercentIncreaseEditField.ValueChangedFcn = createCallbackFcn(app, @NASDAQPercentIncreaseEditFieldValueChanged2, true);
            app.NASDAQPercentIncreaseEditField.Editable = 'off';
            app.SP500PercentIncreaseEditFieldLabel = uilabel(app.InvestmentOptionsTab);
            app.SP500PercentIncreaseEditFieldLabel.Text = 'S&P 500 Percent Increase';
            app.SP500PercentIncreaseEditField = uieditfield(app.InvestmentOptionsTab, 'numeric');
            app.SP500PercentIncreaseEditField.ValueChangedFcn = createCallbackFcn(app, @SP500PercentIncreaseEditFieldValueChanged2, true);
            app.SP500PercentIncreaseEditField.Editable = 'off';
            app.Russell3000PercentIncreaseEditFieldLabel = uilabel(app.InvestmentOptionsTab);
            app.Russell3000PercentIncreaseEditFieldLabel.Text = 'Russell 3000 Percent Increase';
            app.Russell3000PercentIncreaseEditField = uieditfield(app.InvestmentOptionsTab, 'numeric');
            app.Russell3000PercentIncreaseEditField.ValueChangedFcn = createCallbackFcn(app, @Russell3000PercentIncreaseEditFieldValueChanged2, true);
            app.Russell3000PercentIncreaseEditField.Editable = 'off';
            app.Russell2000PercentIncreaseEditFieldLabel = uilabel(app.InvestmentOptionsTab);
            app.Russell2000PercentIncreaseEditFieldLabel.Text = 'Russell 2000 Percent Increase';
            app.Russell2000PercentIncreaseEditField = uieditfield(app.InvestmentOptionsTab, 'numeric');
            app.Russell2000PercentIncreaseEditField.ValueChangedFcn = createCallbackFcn(app, @Russell2000PercentIncreaseEditFieldValueChanged2, true);
            app.Russell2000PercentIncreaseEditField.Editable = 'off';
            app.GoldPercentIncreaseEditFieldLabel = uilabel(app.InvestmentOptionsTab);
            app.GoldPercentIncreaseEditFieldLabel.Text = 'Gold Percent Increase';
            app.GoldPercentIncreaseEditField = uieditfield(app.InvestmentOptionsTab, 'numeric');
            app.GoldPercentIncreaseEditField.ValueChangedFcn = createCallbackFcn(app, @GoldPercentIncreaseEditFieldValueChanged2, true);
            app.GoldPercentIncreaseEditField.Editable = 'off';
            app.GridCheckBox_2 = uicheckbox(app.InvestmentOptionsTab);
            app.GridCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @GridCheckBox_2ValueChanged, true);
            app.GridCheckBox_2.Text = 'Grid';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TheApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end